classdef PulseGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppWindow        matlab.ui.Figure
        axPulsePlot      matlab.ui.control.UIAxes
        btnPlot          matlab.ui.control.Button
        swPlotType       matlab.ui.control.Switch
        lmpAutoUpdate    matlab.ui.control.Lamp
        swAutoUpdate     matlab.ui.control.Switch
        lblAutoUpdate    matlab.ui.control.Label
        dknDispersion    matlab.ui.control.DiscreteKnob
        lblDispersion    matlab.ui.control.Label
        dknModulation    matlab.ui.control.DiscreteKnob
        lblModulation    matlab.ui.control.Label
        cknHighPass      matlab.ui.control.Knob
        lblHighPass      matlab.ui.control.Label
        cknWindow        matlab.ui.control.Knob
        lblWindow        matlab.ui.control.Label
        cknLowPass       matlab.ui.control.Knob
        lblLowPass       matlab.ui.control.Label
        cknEdge          matlab.ui.control.Knob
        lblEdge          matlab.ui.control.Label
        ddType           matlab.ui.control.DropDown
        lblType          matlab.ui.control.Label
        numSignalLength  matlab.ui.control.NumericEditField
        lblSignalLength  matlab.ui.control.Label
        numFrequency     matlab.ui.control.NumericEditField
        lblFrequency     matlab.ui.control.Label
    end


    properties (Access = private)
        autoUpdate
    end

    methods (Access = private)
        
        function updatePlot(app)
            
            signalLength = app.numSignalLength.Value ;
            frequency = app.numFrequency.Value ;
            plotType = app.swPlotType.Value ;
             
            p = generatePulse(app) ;
            t = -signalLength/2:1/frequency:signalLength/2;

            if strcmp(plotType,'Pulse')
                plot(app.axPulsePlot, t, p);
                xlabel(app.axPulsePlot,'Time(s)');
            else
                lp=length(p);
                Y=fft(p);
                sig=abs(Y(1:ceil(lp/2)));
                f=linspace(0, frequency/2, ceil(lp/2));
                plot(app.axPulsePlot, f(sig>1e-4), sig(sig>1e-4));
%                 semilogy(app.axPulsePlot, f(sig>1e-4), sig(sig>1e-4));
                xlabel(app.axPulsePlot,'Frequency (Hz)');
            end            
            
        end

        function result = generatePulse(app)
            
            frequency = app.numFrequency.Value ;
            signalLength = app.numSignalLength.Value ;
            edge = app.cknEdge.Value ;
            window = app.cknWindow.Value ;
            modulation = str2num(app.dknModulation.Value) ;
            type = app.ddType.Value ;
            lowpass = app.cknLowPass.Value ;
            highpass = app.cknHighPass.Value ;
            dispersion = str2num(app.dknDispersion.Value) ;
            
            startFrequency = 10 ;
            stopFrequency = 20 ;
            
            t = -signalLength/2:1/frequency:signalLength/2 ;
            sig = (signalLength/(8*edge))^2 ;
            
            switch app.ddType.Value
                
                case 'gaussian'
                    y = exp(-(t).^2/sig) ;
                case 'sinc'
                    x = 2*pi*edge*50.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                case 'square'
                    y = (t > -signalLength/edge/2) & (t < signalLength/edge/2) ;
                case 'triangle'
                    y = (t + signalLength/edge/2).*(t < 0) - (t - signalLength/edge/2).*(t >= 0);
                    y(y < 0) = 0 ;
                case 'monocycle'
                    if (sig == 0)
                        y = t ;
                    else
                        y = 2*t./sig.*exp(-(t).^2/sig) ;
                    end
                case 'exponential'
                    y = exp(-t*8*edge/signalLength) ;
                    y(t<0) = 0 ;
                case 'biexponential'
                    y = exp(-abs(t)*8*edge/signalLength) ;
                case 'mexican hat'
                    z = t./sqrt(0.75*sig);
                    y = sqrt(1/2*pi).*(1-z.^2).*exp(-z.^2/2) ;
                case 'raised cosine'
                    rb = 2*edge*50/(5*signalLength);
                    x = pi.*t.*rb ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.*(cos(2*pi*rb.*t)./(1 - (4*rb.*t).^2));
                case 'double sinc'
                    x1 = 2*stopFrequency*pi.*t ;
                    x2 = 2*startFrequency*pi.*t ;
                    y1 = sin(x1)./x1 ;
                    y1(x1==0) = 1 ;
                    y2 = sin(x2)./x2 ;
                    y2(x2==0) = 1 ;
                    y = stopFrequency*y1 - startFrequency*y2;
                case 'sinc squared'
                    x = 2*pi*edge*16.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.^2 ;
                case 'sweep'
                    theta = startFrequency.*(t + signalLength/2) + ...
                        ((stopFrequency - startFrequency)/(signalLength)).*(t+signalLength/2).^2;
                    y = real(exp(1j*(2*pi.*theta - pi/2)));
            end
            
            if (lowpass < 1) || (highpass > 0) || (dispersion ~= 0)
                c = length(y) ;
            end
            
            s = fft(y);
            sA = abs(s);
            sP = angle(s);
            if (lowpass < 1)
                cP = ceil(lowpass*c/2);
                if (cP == 0)
                    sA(:) = 0;
                else
                    sA(cP:end-cP+2) = 0;
                end
            end
            
            if (highpass > 0)
                cP = floor(highpass*c/2);
                if (cP ~= 0)
                    sA(1:cP) = 0;
                    sA(end-cP+2:end) = 0;
                end
            end
            
            if (dispersion ~= 0)
                pp = dispersion.*linspace(0,2*pi,c);
                sP = sP + pp;
            end
            s2 = sA.*cos(sP) + 1j*sA.*sin(sP);
            y = real(ifft(s2));
            
            if (window > 0)
                c = length(y);
                w = ones(size(y));
                p1 = floor(c*window/2);
                % Window is defined in three sections: taper, constant, taper
                w(1:p1+1) = (-cos((0:p1)/p1*pi)+1)/2;
                w(end-p1:end) = (cos((0:p1)/p1*pi)+1)/2;
                y=w.*y;
            end
            
            if modulation ~= 0
                y = y.*cos(pi*t*modulation);
            end
            result = y./max(abs(y)) ;            
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.swAutoUpdate.Value = 'on';
            app.swPlotType.Value = 'Pulse' ;
            app.btnPlot.Visible = 'off' ;
            app.autoUpdate = 1 ;
            updatePlot(app)
        end

        % Value changed function: swAutoUpdate
        function swAutoUpdateValueChanged(app, event)
            if strcmp(app.swAutoUpdate.Value, 'on')
                app.autoUpdate = 1 ;
                app.btnPlot.Visible = 'off' ;
                app.lmpAutoUpdate.Color = [0 1 0] ;
            else
                app.autoUpdate = 0 ;
                app.btnPlot.Visible = 'on' ;
                app.lmpAutoUpdate.Color = [0.5 0.5 0.5] ;  
            end            
        end

        % Button pushed function: btnPlot
        function btnPlotButtonPushed(app, event)
            updatePlot(app)            
        end

        % Value changed function: numFrequency
        function numFrequencyValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: numSignalLength
        function numSignalLengthValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: ddType
        function ddTypeValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end            
        end

        % Value changed function: dknDispersion
        function dknDispersionValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: swPlotType
        function swPlotTypeValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: cknLowPass
        function cknLowPassValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: dknModulation
        function dknModulationValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: cknEdge
        function cknEdgeValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: cknWindow
        function cknWindowValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: cknHighPass
        function cknHighPassValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppWindow = uifigure;
            app.AppWindow.Name = 'Pulse Generator';
            setAutoResize(app, app.AppWindow, true)
            app.axPulsePlot = uiaxes(app.AppWindow);
            xlabel(app.axPulsePlot, 'X');
            ylabel(app.axPulsePlot, 'Y');
            app.btnPlot = uibutton(app.AppWindow, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotButtonPushed, true);
            app.btnPlot.Text = 'Plot';
            app.swPlotType = uiswitch(app.AppWindow, 'slider');
            app.swPlotType.Items = {'Pulse', 'Pulse Spectrum'};
            app.swPlotType.ValueChangedFcn = createCallbackFcn(app, @swPlotTypeValueChanged, true);
            app.swPlotType.Value = 'Pulse Spectrum';
            app.lmpAutoUpdate = uilamp(app.AppWindow);
            app.swAutoUpdate = uiswitch(app.AppWindow, 'slider');
            app.swAutoUpdate.Items = {'off', 'on'};
            app.swAutoUpdate.ValueChangedFcn = createCallbackFcn(app, @swAutoUpdateValueChanged, true);
            app.swAutoUpdate.Value = 'on';
            app.lblAutoUpdate = uilabel(app.AppWindow);
            app.lblAutoUpdate.Text = 'Auto Update';
            app.dknDispersion = uiknob(app.AppWindow, 'discrete');
            app.dknDispersion.Items = {'-1.0', '-0.5', '0', '0.5', '1.0'};
            app.dknDispersion.ValueChangedFcn = createCallbackFcn(app, @dknDispersionValueChanged, true);
            app.dknDispersion.Value = '0';
            app.lblDispersion = uilabel(app.AppWindow);
            app.lblDispersion.Text = 'Dispersion';
            app.dknModulation = uiknob(app.AppWindow, 'discrete');
            app.dknModulation.Items = {'0', '50', '100', '150', '200', '250'};
            app.dknModulation.ValueChangedFcn = createCallbackFcn(app, @dknModulationValueChanged, true);
            app.dknModulation.Value = '0';
            app.lblModulation = uilabel(app.AppWindow);
            app.lblModulation.Text = 'Modulation';
            app.cknHighPass = uiknob(app.AppWindow, 'continuous');
            app.cknHighPass.Limits = [0 1];
            app.cknHighPass.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknHighPass.ValueChangedFcn = createCallbackFcn(app, @cknHighPassValueChanged, true);
            app.lblHighPass = uilabel(app.AppWindow);
            app.lblHighPass.Text = 'High Pass';
            app.cknWindow = uiknob(app.AppWindow, 'continuous');
            app.cknWindow.Limits = [0 1];
            app.cknWindow.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknWindow.ValueChangedFcn = createCallbackFcn(app, @cknWindowValueChanged, true);
            app.lblWindow = uilabel(app.AppWindow);
            app.lblWindow.Text = 'Window';
            app.cknLowPass = uiknob(app.AppWindow, 'continuous');
            app.cknLowPass.Limits = [0 1];
            app.cknLowPass.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknLowPass.ValueChangedFcn = createCallbackFcn(app, @cknLowPassValueChanged, true);
            app.cknLowPass.Value = 0.4;
            app.lblLowPass = uilabel(app.AppWindow);
            app.lblLowPass.Text = 'Low Pass';
            app.cknEdge = uiknob(app.AppWindow, 'continuous');
            app.cknEdge.Limits = [0 4];
            app.cknEdge.ValueChangedFcn = createCallbackFcn(app, @cknEdgeValueChanged, true);
            app.cknEdge.Value = 1;
            app.lblEdge = uilabel(app.AppWindow);
            app.lblEdge.Text = 'Edge';
            app.ddType = uidropdown(app.AppWindow);
            app.ddType.Items = {'gaussian', 'sinc', 'square', 'triangle', 'monocycle', 'exponential', 'biexponential', 'mexican hat', 'raised cosine', 'double sinc', 'sinc squared', 'sweep'};
            app.ddType.ValueChangedFcn = createCallbackFcn(app, @ddTypeValueChanged, true);
            app.ddType.Value = 'gaussian';
            app.lblType = uilabel(app.AppWindow);
            app.lblType.Text = 'Type';
            app.numSignalLength = uieditfield(app.AppWindow, 'numeric');
            app.numSignalLength.ValueChangedFcn = createCallbackFcn(app, @numSignalLengthValueChanged, true);
            app.numSignalLength.Limits = [1 Inf];
            app.numSignalLength.Value = 2;
            app.lblSignalLength = uilabel(app.AppWindow);
            app.lblSignalLength.Text = 'Signal Length (s)';
            app.numFrequency = uieditfield(app.AppWindow, 'numeric');
            app.numFrequency.ValueChangedFcn = createCallbackFcn(app, @numFrequencyValueChanged, true);
            app.numFrequency.Value = 500;
            app.lblFrequency = uilabel(app.AppWindow);
            app.lblFrequency.Text = 'Frequency';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PulseGenerator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppWindow)
        end
    end
end